classdef RNAseqAuto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RNAseqAutoUIFigure          matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        AnalysisTypeButtonGroup     matlab.ui.container.ButtonGroup
        GeneButton                  matlab.ui.control.RadioButton
        ExonButton                  matlab.ui.control.RadioButton
        OutputFilesPanel            matlab.ui.container.Panel
        SingleSheetCheckBox         matlab.ui.control.CheckBox
        SortedbyGeneIDsCheckBox     matlab.ui.control.CheckBox
        NotefficientLabel           matlab.ui.control.Label
        InputPanel                  matlab.ui.container.Panel
        Counts_SummaryLabel         matlab.ui.control.Label
        BrowseCounts_SummaryButton  matlab.ui.control.Button
        c_NumberofFilesLabel        matlab.ui.control.Label
        c_FilenumLabel              matlab.ui.control.Label
        c_ErrorLabel                matlab.ui.control.Label
        c_LocationLabel             matlab.ui.control.Label
        c_PathLabel                 matlab.ui.control.Label
        RowsLabel                   matlab.ui.control.Label
        BrowseRowsButton            matlab.ui.control.Button
        r_FilenameLabel             matlab.ui.control.Label
        r_FileLabel                 matlab.ui.control.Label
        r_LocationLabel             matlab.ui.control.Label
        r_PathLabel                 matlab.ui.control.Label
        DataAnalysedButtonGroup     matlab.ui.container.ButtonGroup
        AlldataButton               matlab.ui.control.RadioButton
        SelectgenesrowsButton       matlab.ui.control.RadioButton
        ProviderowsLabel            matlab.ui.control.Label
        HelpImage                   matlab.ui.control.Image
        ResetButton                 matlab.ui.control.Button
        ErrorLabel                  matlab.ui.control.Label
        RunAnalysisButton           matlab.ui.control.StateButton
    end

    % LE 5th January 2021
    
    properties (Access = private)
        % Startup
        add_lib = addpath(genpath(fullfile(pwd, 'lib/')));
        lastBrowsePath;
        outputType=[0,0];

        % Browse files
        c_files=0; %counts + matched summary file/s
        c_path=0;
        r_file=0; %row file
        r_path=0;
    end
    
    methods (Access = private)
        
        function readyToRun (app)
            if ~isequal(app.c_files,0) && mod(size(app.c_files,2),2) == 0 && any(app.outputType)
                if isequal(app.AnalysisTypeButtonGroup.SelectedObject.Tag, 'Gene_select') && isequal(app.r_file,0)
                    app.RunAnalysisButton.Enable  = 'off';
                else
                    app.RunAnalysisButton.Enable  = 'on';
                end    
            else
                app.RunAnalysisButton.Enable = 'off';
            end 
        end        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Change the current directory
            [directory,~,~] = fileparts(mfilename('fullpath'));
            cd (directory);
            % Set last browsing path to current directory
            app.lastBrowsePath = fullfile(pwd, filesep);

        end

        % Image clicked function: HelpImage
        function HelpImageClicked(app, event)
            app.HelpImage.Enable = "off";
            open("RNAseqAuto_3.0 User Guide.pdf");
            pause(5);
            app.HelpImage.Enable = "on";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.c_FilenumLabel.Text = '';
            app.c_NumberofFilesLabel.Visible = 'on';
            app.c_ErrorLabel.Text = '';
            app.c_PathLabel.Text = '';
            app.c_NumberofFilesLabel.Visible = 'off';
            app.c_LocationLabel.Visible = 'off';
            
            app.r_FileLabel.Text = '';
            app.r_PathLabel.Text = '';
            app.r_FilenameLabel.Visible = 'off';
            app.r_LocationLabel.Visible = 'off';
            
            app.c_files = 0;
            app.r_file = 0;
            app.ErrorLabel.Text = '';
            
            app.SingleSheetCheckBox.Value = false;
            app.SortedbyGeneIDsCheckBox.Value = false;
            
            app.readyToRun;
        end

        % Selection changed function: AnalysisTypeButtonGroup
        function AnalysisTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysisTypeButtonGroup.SelectedObject;
            
            app.c_FilenumLabel.Text = '';
            app.c_NumberofFilesLabel.Visible = 'on';
            app.c_ErrorLabel.Text = '';
            app.c_PathLabel.Text = '';
            app.c_NumberofFilesLabel.Visible = 'off';
            app.c_LocationLabel.Visible = 'off';
            
            app.c_files = 0;
            app.r_file = 0;
            
            if isequal(selectedButton.Tag, 'Exon')
                app.AlldataButton.Value = true;
                app.DataAnalysedButtonGroupSelectionChanged;
                app.SelectgenesrowsButton.Enable = "off";
                app.ProviderowsLabel.Enable = "off";
                app.r_FileLabel.Text = '';
                app.r_PathLabel.Text = '';
                app.r_FilenameLabel.Visible = 'off';
                app.r_LocationLabel.Visible = 'off';
                app.r_file = 0;
            else
                app.DataAnalysedButtonGroupSelectionChanged;
                app.SelectgenesrowsButton.Enable = "on";
                app.ProviderowsLabel.Enable = "on";
            end
            app.readyToRun;
        end

        % Value changed function: SingleSheetCheckBox, 
        % SortedbyGeneIDsCheckBox
        function OutputFilesCheckBoxValueChanged(app, event)
            app.outputType = [app.SingleSheetCheckBox.Value app.SortedbyGeneIDsCheckBox.Value];
            app.readyToRun;
            
        end

        % Button pushed function: BrowseCounts_SummaryButton
        function BrowseCounts_SummaryButtonPushed(app, event)
            app.RNAseqAutoUIFigure.Visible = 'off';
            [file,path] = uigetfile({'*.tabular', 'Tabular Spreadsheets Files (*.tabular)'; '*.*',  'All Files (*.*)'}, 'Select Counts + Matched Summary File/s', app.lastBrowsePath, 'MultiSelect', 'on');
            app.RNAseqAutoUIFigure.Visible = 'on'; 

            app.c_ErrorLabel.Text = '';
            
            if isequal(file,0) % user pressed cancel
                app.c_FilenumLabel.Text = '';
                app.c_PathLabel.Text = '';
                app.c_NumberofFilesLabel.Visible = 'off';
                app.c_LocationLabel.Visible = 'off';

            else
                app.c_path = path;
                app.lastBrowsePath = fullfile(path, filesep);
                app.c_files = cellstr(file); %cell array
                app.c_FilenumLabel.Text = num2str(size(app.c_files,2));
                if mod(size(app.c_files,2),2) ~= 0
                    app.c_ErrorLabel.Text = "Error: Number of files selected isn't even (not paired)";
                end
                
                str = strsplit(path, filesep);
                path = strjoin(str, filesep+" ");
                wrapLabelText(app.c_PathLabel, path);
                app.c_NumberofFilesLabel.Visible = 'on';
                app.c_LocationLabel.Visible = 'on';
            end
            app.readyToRun;
        end

        % Button pushed function: BrowseRowsButton
        function BrowseRowsButtonPushed(app, event)
            app.RNAseqAutoUIFigure.Visible = 'off';
            [file,path] = uigetfile({'*.txt', 'Text Files (*.txt)'; '*.xlsx; *.csv', 'Spreadsheet/Text Files (*.xlsx,*.csv)'; '*.*',  'All Files (*.*)'}, 'Select Rows File', app.lastBrowsePath);
            app.RNAseqAutoUIFigure.Visible = 'on'; 
                       
            app.r_file = file;
            app.r_path = path; 
            
            if isequal(file,0) % user pressed cancel
                app.r_FileLabel.Text = '';
                app.r_PathLabel.Text = '';
                app.r_FilenameLabel.Visible = 'off';
                app.r_LocationLabel.Visible = 'off';
            else
                app.lastBrowsePath = fullfile(path, filesep);
                str = strsplit(path, filesep);
                path = strjoin(str, filesep+" ");
                app.r_FileLabel.Text = file;
                wrapLabelText(app.r_PathLabel, path);
                app.r_FilenameLabel.Visible = 'on';
                app.r_LocationLabel.Visible = 'on';
            end
            app.readyToRun;  
        end

        % Value changed function: RunAnalysisButton
        function RunAnalysisButtonValueChanged(app, event)
            try
                app.RNAseqAutoUIFigure.Visible = 'off';
                app.ErrorLabel.Text = '';
                hideLabel = uilabel("Text", "", "BackgroundColor", [0.65,0.65,0.65], "Enable", "off",...
                    "Position", [1,1,620,635], "Parent", app.RNAseqAutoUIFigure);
                analysisLabel = uilabel("Text", "Analysing Data...", "FontSize", 45, "HorizontalAlignment", "center",...
                    "BackgroundColor", [1,1,1], "Position", [90,310,440,150], "Parent", app.RNAseqAutoUIFigure);
                pause(1); % wait for Windows computer to add labels
                app.RNAseqAutoUIFigure.Visible = 'on';
                
                if isequal(app.AnalysisTypeButtonGroup.SelectedObject.Tag, 'Gene') && isequal(app.DataAnalysedButtonGroup.SelectedObject.Tag, 'Select')
                    import_and_calculate('Gene_select', app.c_files, app.c_path, app.r_file, app.r_path, app.outputType);
                elseif isequal(app.AnalysisTypeButtonGroup.SelectedObject.Tag, 'Gene') && isequal(app.DataAnalysedButtonGroup.SelectedObject.Tag, 'All')
                    import_and_calculate('Gene_all', app.c_files, app.c_path, 0, 0, app.outputType);
                elseif isequal(app.AnalysisTypeButtonGroup.SelectedObject.Tag, 'Exon')
                    import_and_calculate('Exon', app.c_files, app.c_path, 0, 0, app.outputType);
                end
                
                delete(hideLabel);
                delete(analysisLabel);
            catch ME
                delete(hideLabel);
                delete(analysisLabel);
                app.RNAseqAutoUIFigure.Visible = 'on';
                
                if (contains(ME.identifier,'MATLAB:import_and_calculate:invalidRow'))
                    app.ErrorLabel.Text = ME.message;
                    return
                elseif (contains(ME.identifier,'MATLAB:find_exons_and_calculate:invalidPair'))
                    app.ErrorLabel.Text = "Invalid count files. See 'ERROR:...' in MATLAB window.";
                    rethrow(ME);
                else % rethrow error if something else
                    rethrow(ME);
                end
            end
        end

        % Selection changed function: DataAnalysedButtonGroup
        function DataAnalysedButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataAnalysedButtonGroup.SelectedObject;
            
            if isequal(selectedButton.Tag, 'All')
                app.RowsLabel.Enable = 'off';
                app.BrowseRowsButton.Enable = 'off';
                app.r_FileLabel.Text = '';
                app.r_PathLabel.Text = '';
                app.r_FilenameLabel.Visible = 'off';
                app.r_LocationLabel.Visible = 'off';
                app.r_file = 0;
            else
                app.RowsLabel.Enable = 'on';
                app.BrowseRowsButton.Enable = 'on';
            end
            
            app.readyToRun;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RNAseqAutoUIFigure = uifigure('Visible', 'off');
            app.RNAseqAutoUIFigure.Name = 'RNAseqAuto';
            app.Panel = uipanel(app.RNAseqAutoUIFigure);
            app.AnalysisTypeButtonGroup = uibuttongroup(app.Panel);
            app.AnalysisTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTypeButtonGroupSelectionChanged, true);
            app.AnalysisTypeButtonGroup.Title = 'Analysis Type';
            app.GeneButton = uiradiobutton(app.AnalysisTypeButtonGroup);
            app.GeneButton.Tag = 'Gene';
            app.GeneButton.Text = ' Gene';
            app.GeneButton.Value = true;
            app.ExonButton = uiradiobutton(app.AnalysisTypeButtonGroup);
            app.ExonButton.Tag = 'Exon';
            app.ExonButton.Text = ' Exon';
            app.OutputFilesPanel = uipanel(app.Panel);
            app.OutputFilesPanel.Title = 'Output Files';
            app.SingleSheetCheckBox = uicheckbox(app.OutputFilesPanel);
            app.SingleSheetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputFilesCheckBoxValueChanged, true);
            app.SingleSheetCheckBox.Text = ' Single Sheet';
            app.SortedbyGeneIDsCheckBox = uicheckbox(app.OutputFilesPanel);
            app.SortedbyGeneIDsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputFilesCheckBoxValueChanged, true);
            app.SortedbyGeneIDsCheckBox.Text = ' Sorted by GeneIDs';
            app.NotefficientLabel = uilabel(app.OutputFilesPanel);
            app.NotefficientLabel.Text = '(Not efficient for large files)';
            app.InputPanel = uipanel(app.Panel);
            app.InputPanel.Title = 'Input Files';
            app.Counts_SummaryLabel = uilabel(app.InputPanel);
            app.Counts_SummaryLabel.Text = 'Counts + Matched Summary Files (required)';
            app.BrowseCounts_SummaryButton = uibutton(app.InputPanel, 'push');
            app.BrowseCounts_SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCounts_SummaryButtonPushed, true);
            app.BrowseCounts_SummaryButton.Text = 'Browse';
            app.c_NumberofFilesLabel = uilabel(app.InputPanel);
            app.c_NumberofFilesLabel.Visible = 'off';
            app.c_NumberofFilesLabel.Text = 'Number of Files:';
            app.c_FilenumLabel = uilabel(app.InputPanel);
            app.c_FilenumLabel.Text = '';
            app.c_ErrorLabel = uilabel(app.InputPanel);
            app.c_ErrorLabel.Text = '';
            app.c_LocationLabel = uilabel(app.InputPanel);
            app.c_LocationLabel.Visible = 'off';
            app.c_LocationLabel.Text = 'File Location:';
            app.c_PathLabel = uilabel(app.InputPanel);
            app.c_PathLabel.Text = '';
            app.RowsLabel = uilabel(app.InputPanel);
            app.RowsLabel.Enable = 'off';
            app.RowsLabel.Text = 'Rows Text File (required)';
            app.BrowseRowsButton = uibutton(app.InputPanel, 'push');
            app.BrowseRowsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRowsButtonPushed, true);
            app.BrowseRowsButton.Enable = 'off';
            app.BrowseRowsButton.Text = 'Browse';
            app.r_FilenameLabel = uilabel(app.InputPanel);
            app.r_FilenameLabel.Visible = 'off';
            app.r_FilenameLabel.Text = 'Filename:';
            app.r_FileLabel = uilabel(app.InputPanel);
            app.r_FileLabel.Text = '';
            app.r_LocationLabel = uilabel(app.InputPanel);
            app.r_LocationLabel.Visible = 'off';
            app.r_LocationLabel.Text = 'File Location:';
            app.r_PathLabel = uilabel(app.InputPanel);
            app.r_PathLabel.Text = '';
            app.DataAnalysedButtonGroup = uibuttongroup(app.Panel);
            app.DataAnalysedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataAnalysedButtonGroupSelectionChanged, true);
            app.DataAnalysedButtonGroup.Title = 'Data Analysed';
            app.AlldataButton = uiradiobutton(app.DataAnalysedButtonGroup);
            app.AlldataButton.Tag = 'All';
            app.AlldataButton.Text = ' All data';
            app.AlldataButton.Value = true;
            app.SelectgenesrowsButton = uiradiobutton(app.DataAnalysedButtonGroup);
            app.SelectgenesrowsButton.Tag = 'Select';
            app.SelectgenesrowsButton.Text = ' Select genes/rows';
            app.ProviderowsLabel = uilabel(app.DataAnalysedButtonGroup);
            app.ProviderowsLabel.Text = '(provide rows text file)';
            app.HelpImage = uiimage(app.RNAseqAutoUIFigure);
            app.HelpImage.ImageClickedFcn = createCallbackFcn(app, @HelpImageClicked, true);
            app.HelpImage.ImageSource = 'help_icon_dkgrey.png';
            app.ResetButton = uibutton(app.RNAseqAutoUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ErrorLabel = uilabel(app.RNAseqAutoUIFigure);
            app.ErrorLabel.Text = '';
            app.RunAnalysisButton = uibutton(app.RNAseqAutoUIFigure, 'state');
            app.RunAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Text = 'Run Analysis';
            % Show the figure after all components are created
            app.RNAseqAutoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RNAseqAuto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RNAseqAutoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RNAseqAutoUIFigure)
        end
    end
end